classdef analisis_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        MinPeakHeightuVEditField       matlab.ui.control.NumericEditField
        MinPeakHeightuVEditFieldLabel  matlab.ui.control.Label
        MinPeakDistanceEditField       matlab.ui.control.NumericEditField
        MinPeakDistanceEditFieldLabel  matlab.ui.control.Label
        AnalisisButton                 matlab.ui.control.Button
        GenerarxlsxButton              matlab.ui.control.Button
        AbrirButton                    matlab.ui.control.Button
        Image                          matlab.ui.control.Image
        GuardarenButton                matlab.ui.control.Button
        guardarenEditField             matlab.ui.control.EditField
        carpetasListBox                matlab.ui.control.ListBox
        SelecBMPButton                 matlab.ui.control.Button
        bpmfolderEditField             matlab.ui.control.EditField
        outpytsystemTextArea           matlab.ui.control.TextArea
        SeleccionarButton              matlab.ui.control.Button
        seleccionardatosEditField      matlab.ui.control.EditField
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
       BMPfiles_L
       dataDIR_L
       exportDIR_L
       EEG_timestamps_L
       debug
       data_json
        canales = {'tiempo','Fp1-A1' , 'Fp2-A2','F3-A1','F4-A2','C3-A1','C4-A2','P3-A1','P4-A2','O1-A1','O2-A2','F7-A1','F8-A2','T3-A1',...
           'T4-A2','T5-A1','T6-A2','Add_lead1','Add_lead2','Add_lead3','Add_lead4','Add_lead5','Add_lead6','Add_lead7','Add_lead8','Add_lead9','Add_lead10','EEG'};
       %canales_data=[1,2,3,4,5,6,7,8,9,10,11,12,13,14,15,16,17,18,19,20,21,22,23,24,25,26,27];
       MinPeakHeight_L
       MinPeakDistance_L
       lista
       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Image.ImageSource = fullfile('figures','waiting_forBMP.gif');
            load("memoria_analisis.mat","BMPfiles");load("memoria_analisis.mat","dataDIR");load("memoria_analisis.mat","exportDIR");
            load("memoria_analisis.mat","MinPeakHeight");load("memoria_analisis.mat","MinPeakDistance");
            %
            app.GenerarxlsxButton.Enable="off";
            app.AnalisisButton.Enable="off";
            %
            app.seleccionardatosEditField.Editable="off";
            app.bpmfolderEditField.Editable="off";
            app.guardarenEditField.Editable="off";
            %cargamos los datos de memoria
            app.BMPfiles_L = BMPfiles;
            app.dataDIR_L = dataDIR;
            app.exportDIR_L = exportDIR;
            app.MinPeakHeight_L = MinPeakHeight;
            app.MinPeakDistance_L = MinPeakDistance;
            %modificamos los valores de los campos de edición
            app.seleccionardatosEditField.Value = app.dataDIR_L;
            app.bpmfolderEditField.Value = app.BMPfiles_L;
            app.guardarenEditField.Value = app.exportDIR_L;
            app.MinPeakHeightuVEditField.Value = app.MinPeakHeight_L;
            app.MinPeakDistanceEditField.Value = app.MinPeakDistance_L;
            %
            app.debug = "Seleccione sus datos. [OK] Variables de memoria cargadas."+...
                newline+"Los datos exportados serán guardados en "+convertCharsToStrings(app.exportDIR_L);% + convertCharsToStrings(convert_timestamp(app.EEG_timestamps_L));
            app.outpytsystemTextArea.Value = app.debug;
        end

        % Button pushed function: SeleccionarButton
        function SeleccionarButtonPushed(app, event)
            directorio = uigetdir;
            if directorio == 0
                app.seleccionardatosEditField.Value = app.dataDIR_L;
            else
                app.seleccionardatosEditField.Value = directorio;
                dataDIR = directorio;
                app.dataDIR_L = dataDIR;
                save('memoria_analisis.mat','dataDIR','-append');
                app.debug =  "[OK] Directorio de datos cargado " + convertCharsToStrings(directorio)+ newline+app.debug;
                app.outpytsystemTextArea.Value = app.debug;
            end
        end

        % Button pushed function: SelecBMPButton
        function SelecBMPButtonPushed(app, event)
            directorio = uigetdir;
            if directorio == 0
                app.bpmfolderEditField.Value = app.BMPfiles_L;
            else
                app.bpmfolderEditField.Value = directorio;
                BMPfiles = directorio;
                app.BMPfiles_L = BMPfiles;
                save('memoria_analisis.mat','BMPfiles','-append');
                app.debug =  "[OK] Directorio BMP cargado " + convertCharsToStrings(directorio)+newline+app.debug;
                app.outpytsystemTextArea.Value = app.debug;
            end
        end

        % Button pushed function: GuardarenButton
        function GuardarenButtonPushed(app, event)
            directorio = uigetdir;
            if directorio == 0
                app.guardarenEditField.Value = app.BMPfiles_L;
            else
                app.guardarenEditField.Value = directorio;
                exportDIR = directorio;
                app.exportDIR_L = exportDIR;
                save('memoria_analisis.mat','exportDIR','-append');
                app.debug =  "[OK] Los datos serán guardados en" + convertCharsToStrings(directorio)+newline+app.debug;
                app.outpytsystemTextArea.Value = app.debug;
            end
        end

        % Value changed function: MinPeakDistanceEditField
        function MinPeakDistanceEditFieldValueChanged(app, event)
            value = app.MinPeakDistanceEditField.Value;
            MinPeakDistance = value;
            app.MinPeakDistance_L = MinPeakDistance;
            app.debug = "Variable MinPeakDistance actualizada. " +newline+app.debug;
            app.outpytsystemTextArea.Value = app.debug;
            save('memoria_analisis.mat',"MinPeakDistance","-append")
        end

        % Value changed function: MinPeakHeightuVEditField
        function MinPeakHeightuVEditFieldValueChanged(app, event)
            value = app.MinPeakHeightuVEditField.Value;
            MinPeakHeight = value;
            app.MinPeakHeight_L = MinPeakHeight;
            app.debug = "Variable MinPeakHeight actualizada."+newline+app.debug;
            app.outpytsystemTextArea.Value = app.debug;
            save('memoria_analisis.mat',"MinPeakHeight","-append")
            
        end

        % Button pushed function: AbrirButton
        function AbrirButtonPushed(app, event)
            app.debug = "Verificando exitencia de archivos necesarios..."+newline+app.debug;
            app.outpytsystemTextArea.Value = app.debug;
            if isempty(listaArchivos(app.BMPfiles_L,'*.bmp')) == 1
               app.debug = "[X] Carpeta de imagenes no contiene archivos BMP. ABORTANDO"+newline+app.debug;
               app.outpytsystemTextArea.Value = app.debug;
               c_bmp = 0;
            else
                NUM = length(listaArchivos(app.BMPfiles_L,'*.bmp'));
                app.debug = "[OK] Num. Archivos BMP = " + int2str(NUM)+newline+app.debug;
                app.outpytsystemTextArea.Value = app.debug;
                c_bmp = 1;
            end
            %%%%%%%
            % listamos las carpetas en el directorio 
            lista_directorios = SubDirectorios1nivel(app.dataDIR_L);
            app.lista={};
            if isempty(lista_directorios) == 1
                app.debug = "[X] Carpeta vacía."+newline+app.debug;
                app.outpytsystemTextArea.Value = app.debug;
            else
                for i = 1:length(lista_directorios)
                    directorio =  fullfile(app.dataDIR_L,convertCharsToStrings(lista_directorios(i)));
                    c_pupil = validador_pupil(directorio);
                    c_eeg = validador_eeg24_tarea(directorio);
                    if c_pupil == 1 && c_eeg == 1
                            app.lista(end+1)=lista_directorios(i);
                            
                    end
                end
                if length(app.lista) >= 2 && c_bmp == 1
                   app.carpetasListBox.Items = app.lista;
                   app.GenerarxlsxButton.Enable ="on";
                   app.debug = "[OK] Carpetas compatibles encontradas. Total = "+int2str(length(app.lista))+newline+app.debug;
                   app.outpytsystemTextArea.Value = app.debug;
                else
                   app.debug = "[X] Carpetas incompatibles o imágenes BMP no encontradas."+newline+app.debug;
                   app.outpytsystemTextArea.Value = app.debug;
                end
            end
            
        end

        % Button pushed function: GenerarxlsxButton
        function GenerarxlsxButtonPushed(app, event)
            app.debug = "Generando hojas de datos XLSX."+newline+app.debug;
            app.outpytsystemTextArea.Value = app.debug;
            app.SeleccionarButton.Enable="off";
            app.SelecBMPButton.Enable = "off";
            app.GuardarenButton.Enable = "off";
            app.AbrirButton.Enable="off";
            app.GenerarxlsxButton.Enable="off";
            app.AnalisisButton.Enable = "off";
            app.MinPeakDistanceEditField.Editable = "off";
            app.MinPeakHeightuVEditField.Editable = "off";
            for i = 1:length(app.lista)
                %% generamos los nombres de los archivos
                ID=convertStringsToChars(strjoin(regexp(convertCharsToStrings(app.lista(i)),'[0-9]','match'),''));
                archivo_eegk = ['EEGK_' ID '.xlsx'];
                archivo_pupil_diam= ['pupil_diam' ID '.xlsx'];
                archivo_xy_pos = ['xy_pos' ID '.xlsx'];
                subdataDIR = fullfile(app.dataDIR_L,char(app.lista(i)));
                emoFlechas_file = EmoFlechas_finder(fullfile(subdataDIR,'eeg_tarea'));
                eegk_file = EEGdata_finder(fullfile(subdataDIR,'eeg_tarea'));
                app.EEG_timestamps_L = load(fullfile(subdataDIR,'eeg_tarea','eeg_timestamp.txt'));
                EmoFlechas_data = loadEmoFlechas(fullfile(subdataDIR,'eeg_tarea',emoFlechas_file));
                EEG_data = loadEEG(fullfile(subdataDIR,'eeg_tarea',eegk_file),app.EEG_timestamps_L);
                %% [EEG-EKG] 
                delete(fullfile(app.exportDIR_L,archivo_eegk));
                app.debug = "Generando "+convertCharsToStrings(archivo_eegk)+newline+app.debug;
                app.outpytsystemTextArea.Value = app.debug;

                app.debug = "Comparando el archivo"+convertCharsToStrings(emoFlechas_file)+" con " +convertCharsToStrings(eegk_file)...
                +newline+app.debug;
                app.outpytsystemTextArea.Value = app.debug;        
                indices = sync_EEG_EF(EEG_data,EmoFlechas_data);
                for j = 1:length(EmoFlechas_data.Var4)
                    signals = zeros(indices(j,2)-indices(j,1)+1,length(app.canales));
                    length(signals)
                    for n = 1:length(app.canales)-1
                        signals(:,n) = EEG_data(indices(j,1):indices(j,2),n);     
                    end
                    signals(:,end) = EEG_data(indices(j,1):indices(j,2),20)-EEG_data(indices(j,1):indices(j,2),19);
                    data_cells=num2cell(signals);
                    output_matrix=[app.canales;  data_cells];
                    writecell(output_matrix,fullfile(app.exportDIR_L,archivo_eegk),'Sheet',char(EmoFlechas_data.Var4(j))); 
                end
                app.debug = "[OK] Archivo escrito: "+convertCharsToStrings(archivo_eegk)+newline+app.debug;
                app.outpytsystemTextArea.Value = app.debug;
                %% [

                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Título')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.seleccionardatosEditField = uieditfield(app.UIFigure, 'text');
            app.SeleccionarButton = uibutton(app.UIFigure, 'push');
            app.SeleccionarButton.ButtonPushedFcn = createCallbackFcn(app, @SeleccionarButtonPushed, true);
            app.SeleccionarButton.Text = 'Seleccionar';
            app.outpytsystemTextArea = uitextarea(app.UIFigure);
            app.outpytsystemTextArea.Value = {'output'};
            app.bpmfolderEditField = uieditfield(app.UIFigure, 'text');
            app.SelecBMPButton = uibutton(app.UIFigure, 'push');
            app.SelecBMPButton.ButtonPushedFcn = createCallbackFcn(app, @SelecBMPButtonPushed, true);
            app.SelecBMPButton.Text = 'Selec. BMP';
            app.carpetasListBox = uilistbox(app.UIFigure);
            app.carpetasListBox.Items = {'Sujeto1', 'Sujeto2', 'Sujeto3', 'Sujeto4'};
            app.carpetasListBox.Value = 'Sujeto1';
            app.guardarenEditField = uieditfield(app.UIFigure, 'text');
            app.GuardarenButton = uibutton(app.UIFigure, 'push');
            app.GuardarenButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarenButtonPushed, true);
            app.GuardarenButton.Text = 'Guardar en';
            app.Image = uiimage(app.UIFigure);
            app.AbrirButton = uibutton(app.UIFigure, 'push');
            app.AbrirButton.ButtonPushedFcn = createCallbackFcn(app, @AbrirButtonPushed, true);
            app.AbrirButton.Text = 'Abrir';
            app.GenerarxlsxButton = uibutton(app.UIFigure, 'push');
            app.GenerarxlsxButton.ButtonPushedFcn = createCallbackFcn(app, @GenerarxlsxButtonPushed, true);
            app.GenerarxlsxButton.Text = 'Generar .xlsx';
            app.AnalisisButton = uibutton(app.UIFigure, 'push');
            app.AnalisisButton.Text = 'Analisis';
            app.MinPeakDistanceEditFieldLabel = uilabel(app.UIFigure);
            app.MinPeakDistanceEditFieldLabel.Text = 'MinPeakDistance';
            app.MinPeakDistanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinPeakDistanceEditField.ValueChangedFcn = createCallbackFcn(app, @MinPeakDistanceEditFieldValueChanged, true);
            app.MinPeakHeightuVEditFieldLabel = uilabel(app.UIFigure);
            app.MinPeakHeightuVEditFieldLabel.Text = 'MinPeakHeight uV';
            app.MinPeakHeightuVEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinPeakHeightuVEditField.ValueChangedFcn = createCallbackFcn(app, @MinPeakHeightuVEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = analisis_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end